classdef Zadanie3App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        loha1Tab                       matlab.ui.container.Tab
        AktualizovaButton              matlab.ui.control.Button
        PomerfariebzadvajteakocelslaoddelenmedzerouLabel  matlab.ui.control.Label
        PomerfariebEditField           matlab.ui.control.EditField
        PomerfariebEditFieldLabel      matlab.ui.control.Label
        ZtohojeListBox                 matlab.ui.control.ListBox
        ZtohojeListBoxLabel            matlab.ui.control.Label
        VsledkyLabel                   matlab.ui.control.Label
        NastavihodnotyzozadaniaButton  matlab.ui.control.Button
        VypotapravdepodobnosButton     matlab.ui.control.Button
        numberOfColors_2               matlab.ui.control.Spinner
        VytiahnemeksLabel              matlab.ui.control.Label
        VytiahnutgulikybudButtonGroup  matlab.ui.container.ButtonGroup
        rzneButton                     matlab.ui.control.RadioButton
        rovnakButton                   matlab.ui.control.RadioButton
        numberOfColors                 matlab.ui.control.Spinner
        VfarbchLabel                   matlab.ui.control.Label
        numberOfBalls                  matlab.ui.control.Spinner
        VurnejerovnakchguliiekLabel    matlab.ui.control.Label
        ZadanieLabel                   matlab.ui.control.Label
        loha2Tab                       matlab.ui.container.Tab
        uloha2Label                    matlab.ui.control.Label
        loha3Tab                       matlab.ui.container.Tab
        uloha3Label                    matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function ratioOfBallsInListbox(app)
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberOfColors
        function numberOfColorsValueChanged(app, event)
            app.ZtohojeListBox.Items = {};
            drawnow
            
            countBalls = app.numberOfBalls.Value;
            countColors = app.numberOfColors.Value;
            countOneColor = floor(countBalls / countColors);
            m = mod(countBalls, countColors);
            for i=1:countColors
                countString = num2str(countOneColor);
                if i == 1
                    countString = num2str(countOneColor + m);
                end
                name = ['farba ', num2str(i), ': ', countString, 'ks'];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end

        % Button pushed function: AktualizovaButton
        function AktualizovaButtonPushed(app, event)
            values = app.PomerfariebEditField.Value;
            splitedValues = strsplit(values);
            [~,numberOfValues] = size(splitedValues);
            
            splitedValuesInt = [];

            for i = 1:numberOfValues
                currentValue = str2double(splitedValues(i));
                if isnan(currentValue)
                    uialert(app.UIFigure,'Medzi zadanými hodnotami sa nachádzajú aj iné znaky ako čísla, prípadne medzera na konci navyše','Chyba');
                    return
                end
                splitedValuesInt(end+1) = currentValue;
            end

            app.ZtohojeListBox.Items = {};
            drawnow
            
            for i = 1:numberOfValues
                name = ['hodnota ', num2str(i), ' ', num2str(splitedValuesInt(i))];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loha1Tab = uitab(app.TabGroup);
            app.loha1Tab.Title = 'Úloha 1';
            app.ZadanieLabel = uilabel(app.loha1Tab);
            app.ZadanieLabel.Text = 'Zadanie';
            app.VurnejerovnakchguliiekLabel = uilabel(app.loha1Tab);
            app.VurnejerovnakchguliiekLabel.Text = 'V urne je                          rovnakých guličiek.';
            app.numberOfBalls = uispinner(app.loha1Tab);
            app.numberOfBalls.Limits = [1 Inf];
            app.numberOfBalls.Value = 100;
            app.VfarbchLabel = uilabel(app.loha1Tab);
            app.VfarbchLabel.Text = 'V                          farbách.';
            app.numberOfColors = uispinner(app.loha1Tab);
            app.numberOfColors.Limits = [1 100];
            app.numberOfColors.ValueChangedFcn = createCallbackFcn(app, @numberOfColorsValueChanged, true);
            app.numberOfColors.Value = 1;
            app.VytiahnutgulikybudButtonGroup = uibuttongroup(app.loha1Tab);
            app.VytiahnutgulikybudButtonGroup.Title = 'Vytiahnuté guličky budú';
            app.rovnakButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rovnakButton.Text = 'rovnaké';
            app.rovnakButton.Value = true;
            app.rzneButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rzneButton.Text = 'rôzne';
            app.VytiahnemeksLabel = uilabel(app.loha1Tab);
            app.VytiahnemeksLabel.Text = 'Vytiahneme                          ks.';
            app.numberOfColors_2 = uispinner(app.loha1Tab);
            app.numberOfColors_2.Limits = [0 Inf];
            app.VypotapravdepodobnosButton = uibutton(app.loha1Tab, 'push');
            app.VypotapravdepodobnosButton.Text = 'Vypočítať pravdepodobnosť';
            app.NastavihodnotyzozadaniaButton = uibutton(app.loha1Tab, 'push');
            app.NastavihodnotyzozadaniaButton.Text = 'Nastaviť hodnoty zo zadania';
            app.VsledkyLabel = uilabel(app.loha1Tab);
            app.VsledkyLabel.Text = 'Výsledky';
            app.ZtohojeListBoxLabel = uilabel(app.loha1Tab);
            app.ZtohojeListBoxLabel.Text = 'Z toho je';
            app.ZtohojeListBox = uilistbox(app.loha1Tab);
            app.ZtohojeListBox.Items = {'farba 1: 100ks'};
            app.ZtohojeListBox.Value = 'farba 1: 100ks';
            app.PomerfariebEditFieldLabel = uilabel(app.loha1Tab);
            app.PomerfariebEditFieldLabel.Text = 'Pomer farieb';
            app.PomerfariebEditField = uieditfield(app.loha1Tab, 'text');
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel = uilabel(app.loha1Tab);
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel.Text = 'Pomer farieb zadávajte ako celé čísla oddelené medzerou. Poradie čísla na vstupe spadá pod farbu s daným číslom. Ak súčet hodnôt na vstupe bude menší ako celkový počet guličiek, chýbajúca časť sa rovnomerne rozdelí medzi guličky ktorým nebolo priradený pomer.';
            app.AktualizovaButton = uibutton(app.loha1Tab, 'push');
            app.AktualizovaButton.ButtonPushedFcn = createCallbackFcn(app, @AktualizovaButtonPushed, true);
            app.AktualizovaButton.Text = 'Aktualizovať';
            app.loha2Tab = uitab(app.TabGroup);
            app.loha2Tab.Title = 'Úloha 2';
            app.uloha2Label = uilabel(app.loha2Tab);
            app.uloha2Label.Text = 'uloha 2';
            app.loha3Tab = uitab(app.TabGroup);
            app.loha3Tab.Title = 'Úloha 3';
            app.uloha3Label = uilabel(app.loha3Tab);
            app.uloha3Label.Text = 'uloha 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zadanie3App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end